classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SigmaEditField           matlab.ui.control.NumericEditField
        SigmaEditFieldLabel      matlab.ui.control.Label
        RadiusEditField          matlab.ui.control.NumericEditField
        RadiusEditFieldLabel     matlab.ui.control.Label
        ThresholdEditField       matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel  matlab.ui.control.Label
        GoButton                 matlab.ui.control.Button
        OperatorDropDown         matlab.ui.control.DropDown
        OperatorDropDownLabel    matlab.ui.control.Label
        UploadImageButton        matlab.ui.control.Button
        OutputImage              matlab.ui.control.UIAxes
        EdgeImage                matlab.ui.control.UIAxes
        InputImage               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.bmp','All Image Files'};
            [filename, pathname] = uigetfile(filterspec);
            
            % If the user cancels, return.
            if ~ischar(filename)
                return;
            end
            
            % Construct the full file path.
            filepath = fullfile(pathname, filename);
            
            % Read the image.
            imageData = imread(filepath);

            % Show the input image.
            imshow(imageData, 'Parent', app.InputImage, 'XData', [1 app.InputImage.Position(3)], 'YData', [1 app.InputImage.Position(4)]);
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            % Get image data from axes.
            imageData = getimage(app.InputImage);

            % Get Parameter.
            operator = app.OperatorDropDown.Value;
            threshold = app.ThresholdEditField.Value;
            sigma = app.SigmaEditField.Value;
            radius = app.RadiusEditField.Value;

            % Create image wrapper.
            currentWrapper = utils.ImageWrapperFactory.Create(imageData);

            % Calculate edge image.
            switch operator
                case "Laplace"
                    edgeImageData = currentWrapper.GetLaplacianEdgeImage(threshold);

                    % Calculate segmented image.
                    segmentedImageData = currentWrapper.GetSegmentedImage(edgeImageData, radius);
        
                    % Show the edge image.
                    imshow(edgeImageData, 'Parent', app.EdgeImage, 'XData', [1 app.EdgeImage.Position(3)], 'YData', [1 app.EdgeImage.Position(4)]);
        
                    % Show the segmented image.
                    imshow(segmentedImageData, 'Parent', app.OutputImage, 'XData', [1 app.OutputImage.Position(3)], 'YData', [1 app.OutputImage.Position(4)]);
                case "Laplace of Gaussian"
                    edgeImageData = currentWrapper.GetLaplacianOfGaussianEdgeImage(threshold, sigma);

                    % Calculate segmented image.
                    segmentedImageData = currentWrapper.GetSegmentedImage(edgeImageData, radius);
        
                    % Show the edge image.
                    imshow(edgeImageData, 'Parent', app.EdgeImage, 'XData', [1 app.EdgeImage.Position(3)], 'YData', [1 app.EdgeImage.Position(4)]);
        
                    % Show the segmented image.
                    imshow(segmentedImageData, 'Parent', app.OutputImage, 'XData', [1 app.OutputImage.Position(3)], 'YData', [1 app.OutputImage.Position(4)]);
                case "Sobel"
                    edgeImageData = currentWrapper.GetSobelEdgeImage(threshold);

                    % Calculate segmented image.
                    segmentedImageData = currentWrapper.GetSegmentedImage(edgeImageData, radius);
        
                    % Show the edge image.
                    imshow(edgeImageData, 'Parent', app.EdgeImage, 'XData', [1 app.EdgeImage.Position(3)], 'YData', [1 app.EdgeImage.Position(4)]);
        
                    % Show the segmented image.
                    imshow(segmentedImageData, 'Parent', app.OutputImage, 'XData', [1 app.OutputImage.Position(3)], 'YData', [1 app.OutputImage.Position(4)]);
                case "Prewitt"
                    edgeImageData = currentWrapper.GetPrewittEdgeImage(threshold);

                    % Calculate segmented image.
                    segmentedImageData = currentWrapper.GetSegmentedImage(edgeImageData, radius);
        
                    % Show the edge image.
                    imshow(edgeImageData, 'Parent', app.EdgeImage, 'XData', [1 app.EdgeImage.Position(3)], 'YData', [1 app.EdgeImage.Position(4)]);
        
                    % Show the segmented image.
                    imshow(segmentedImageData, 'Parent', app.OutputImage, 'XData', [1 app.OutputImage.Position(3)], 'YData', [1 app.OutputImage.Position(4)]);
                case "Roberts"
                    edgeImageData = currentWrapper.GetRobertsEdgeImage(threshold);

                    % Calculate segmented image.
                    segmentedImageData = currentWrapper.GetSegmentedImage(edgeImageData, radius);
        
                    % Show the edge image.
                    imshow(edgeImageData, 'Parent', app.EdgeImage, 'XData', [1 app.EdgeImage.Position(3)], 'YData', [1 app.EdgeImage.Position(4)]);
        
                    % Show the segmented image.
                    imshow(segmentedImageData, 'Parent', app.OutputImage, 'XData', [1 app.OutputImage.Position(3)], 'YData', [1 app.OutputImage.Position(4)]);
                case "Canny"
                    edgeImageData = currentWrapper.GetCannyEdgeImage(threshold, sigma);

                    % Calculate segmented image.
                    segmentedImageData = currentWrapper.GetSegmentedImage(edgeImageData, radius);
        
                    % Show the edge image.
                    imshow(edgeImageData, 'Parent', app.EdgeImage, 'XData', [1 app.EdgeImage.Position(3)], 'YData', [1 app.EdgeImage.Position(4)]);
        
                    % Show the segmented image.
                    imshow(segmentedImageData, 'Parent', app.OutputImage, 'XData', [1 app.OutputImage.Position(3)], 'YData', [1 app.OutputImage.Position(4)]);
                otherwise
                    uialert(app.UIFigure, 'Unrecognized operator name', 'Filter Error');
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputImage = uiaxes(app.UIFigure);
            title(app.InputImage, 'Input Image')
            app.InputImage.XTick = [];
            app.InputImage.YTick = [];
            app.InputImage.ZTick = [];
            app.EdgeImage = uiaxes(app.UIFigure);
            title(app.EdgeImage, 'Edge Image')
            app.EdgeImage.XTick = [];
            app.EdgeImage.YTick = [];
            app.EdgeImage.ZTick = [];
            app.OutputImage = uiaxes(app.UIFigure);
            title(app.OutputImage, 'Output Image')
            app.OutputImage.XTick = [];
            app.OutputImage.YTick = [];
            app.OutputImage.ZTick = [];
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.OperatorDropDownLabel = uilabel(app.UIFigure);
            app.OperatorDropDownLabel.Text = 'Operator';
            app.OperatorDropDown = uidropdown(app.UIFigure);
            app.OperatorDropDown.Items = {'Laplace', 'Laplace of Gaussian', 'Sobel', 'Prewitt', 'Roberts', 'Canny'};
            app.OperatorDropDown.Value = 'Laplace';
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdEditField.UpperLimitInclusive = 'off';
            app.ThresholdEditField.Limits = [0 1];
            app.RadiusEditFieldLabel = uilabel(app.UIFigure);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.RadiusEditField.Limits = [0 Inf];
            app.RadiusEditField.ValueDisplayFormat = '%.0f';
            app.SigmaEditFieldLabel = uilabel(app.UIFigure);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.UIFigure, 'numeric');
            app.SigmaEditField.Limits = [0 Inf];
            app.SigmaEditField.Value = 0.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end